classdef app1 < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                       matlab.ui.Figure
      TabGroup                       matlab.ui.container.TabGroup
      ShipSimulationTab              matlab.ui.container.Tab
      StopButton                     matlab.ui.control.Button
      SimulationtimeEditField        matlab.ui.control.NumericEditField
      SimulationtimeEditFieldLabel   matlab.ui.control.Label
      ElapsedtimeEditField           matlab.ui.control.NumericEditField
      ElapsedtimeEditFieldLabel      matlab.ui.control.Label
      StandingbyLabel                matlab.ui.control.Label
      StatusLabel                    matlab.ui.control.Label
      TabGroup2                      matlab.ui.container.TabGroup
      ShipTab                        matlab.ui.container.Tab
      YawrateEditField               matlab.ui.control.NumericEditField
      YawrateEditFieldLabel          matlab.ui.control.Label
      SwayEditField                  matlab.ui.control.NumericEditField
      SwayEditFieldLabel             matlab.ui.control.Label
      SurgeEditField                 matlab.ui.control.NumericEditField
      SurgeEditFieldLabel            matlab.ui.control.Label
      HeadingEditField               matlab.ui.control.NumericEditField
      HeadingEditFieldLabel          matlab.ui.control.Label
      LatitudeEditField              matlab.ui.control.NumericEditField
      LatitudeEditFieldLabel         matlab.ui.control.Label
      LongitudeEditField             matlab.ui.control.NumericEditField
      LongitudeEditFieldLabel        matlab.ui.control.Label
      PropellerTab                   matlab.ui.container.Tab
      TorqueEditField                matlab.ui.control.NumericEditField
      TorqueEditFieldLabel           matlab.ui.control.Label
      FyEditField                    matlab.ui.control.NumericEditField
      FyEditFieldLabel               matlab.ui.control.Label
      FxEditField                    matlab.ui.control.NumericEditField
      FxEditFieldLabel               matlab.ui.control.Label
      SpeedEditField                 matlab.ui.control.NumericEditField
      SpeedEditFieldLabel            matlab.ui.control.Label
      AngleEditField                 matlab.ui.control.NumericEditField
      AngleEditFieldLabel            matlab.ui.control.Label
      PathFollowingTab               matlab.ui.container.Tab
      DistancetopathEditField        matlab.ui.control.NumericEditField
      DistancetopathEditFieldLabel   matlab.ui.control.Label
      LookaheadpointyEditField       matlab.ui.control.NumericEditField
      LookaheadpointyEditFieldLabel  matlab.ui.control.Label
      LookaheadpointxEditField       matlab.ui.control.NumericEditField
      LookaheadpointxEditFieldLabel  matlab.ui.control.Label
      ProjectedpointyEditField       matlab.ui.control.NumericEditField
      ProjectedpointyEditFieldLabel  matlab.ui.control.Label
      ProjectedpointxEditField       matlab.ui.control.NumericEditField
      ProjectedpointxEditFieldLabel  matlab.ui.control.Label
      BetaangleEditField             matlab.ui.control.NumericEditField
      BetaangleEditFieldLabel        matlab.ui.control.Label
      StartButton                    matlab.ui.control.Button
      UIAxes                         matlab.ui.control.UIAxes
      AppDescriptionTab              matlab.ui.container.Tab
      Label_5                        matlab.ui.control.Label
      Label_4                        matlab.ui.control.Label
      Label_3                        matlab.ui.control.Label
      Image                          matlab.ui.control.Image
      Label_2                        matlab.ui.control.Label
      Label                          matlab.ui.control.Label
   end


   properties (Access = public)
      estop = false; % Description
      v;
   end


   % Callbacks that handle component events
   methods (Access = private)

      % Button pushed function: StartButton
      function StartButtonPushed(app, event)
         addpath(genpath('./ShipVisualiser_v1.2'));
         %          assignin('base','axes',app.UIAxes);
         hold(app.UIAxes,"on");
         %          app.UIAxes.PlotBoxAspectRatio = [1 1 1];
         axis(app.UIAxes,'equal')
         set(app.UIAxes,'Color',[0.6 0.8 1]);
         wps = PathWaypoints();
         assignin('base','waypoints',wps);

         app.StandingbyLabel.Text = 'Running Simulink';
         %          output = sim('PropellerModel.slx');

         %          assignin("base","out",output);
         output = evalin('base', 'out');
         app.StandingbyLabel.Text = 'Finished simulation';
%          pause(5);

         app.StandingbyLabel.Text = 'Running visualisation';
         app.SimulationtimeEditField.Value = output.tout(end);

         plot(app.UIAxes,wps(:,2), wps(:,1),'Color',[1 0 0]);
         pause(3);

         axes = app.UIAxes;
         Ship = PlaceObj('Ship_9.ply', axes); %place the ship model

         s = size(output.tout,1);
         dualpods = true; %option to display two pod objects as in real life

         posY = output.eta.Data(:,1);
         posX = output.eta.Data(:,2);
         heading = output.eta.Data(:,3);
         alpha = output.alpha.Data(:,1);

         app.v = VideoWriter('ShipDemo.avi','MPEG-4');
         app.v.Quality = 95;
         open(app.v);

         if ~dualpods
            Pod = PlaceObj('Pod_3.ply', axes);   %place the propeller model (size is exaggerated for visibility)
            MoveObj(Pod,[[0,-6,0],[0,0,0]]);
            %             pause(1);
            for i = 1:s %animate through the simulation output
               pause(0.01);
               yaw = -heading(i);
               MoveObj(Ship,[[posX(i),posY(i),0.5],[0,0,yaw]]);
               MoveObj(Pod,[[posX(i)+6*sin(yaw),posY(i)-6*cos(yaw),0.0],[0,0,yaw-alpha(i)]]);
            end
         else
            Pod1 = PlaceObj('Pod_3.ply', axes);   %place and offset the twin propeller models
            Pod2 = PlaceObj('Pod_3.ply', axes);
            MoveObj(Pod1,[[3,-6,0],[0,0,-1]]);
            MoveObj(Pod2,[[-3,-6,0],[0,0,-1]]);
            %             pause(1)
            for i = 1:100:s %animate through the simulation output
               %                pause(0.01)
               yaw = -heading(i);
               MoveObj(Ship,[[posX(i),posY(i),0.5],[0,0,yaw]]);
               MoveObj(Pod1,[[posX(i)+6*sin(yaw)+3*cos(yaw),posY(i)-6*cos(yaw)+3*sin(yaw),-1],[0,0,yaw-alpha(i)]]);
               MoveObj(Pod2,[[posX(i)+6*sin(yaw)-3*cos(yaw),posY(i)-6*cos(yaw)-3*sin(yaw),-1],[0,0,yaw-alpha(i)]]);
               

               plot(app.UIAxes, posX(i), posY(i), '.y');
               h = plot(app.UIAxes, output.ProjectedPoint.Data(1,2,i), output.ProjectedPoint.Data(1,1,i),'LineWidth', 2,'Marker','o','Color',[0 1 0]);
               l = plot(app.UIAxes, output.LookaheadPoint.Data(1,2,i), output.LookaheadPoint.Data(1,1,i),'LineWidth', 2,'Marker','o','Color',[1 1 1]);
               sh = plot(app.UIAxes, [output.ProjectedPoint.Data(1,2,i) posX(i)], [output.ProjectedPoint.Data(1,1,i) posY(i)], '--k');
               sl = plot(app.UIAxes, [output.LookaheadPoint.Data(1,2,i) posX(i)], [output.LookaheadPoint.Data(1,1,i) posY(i)], '--k');
               drawnow();
               
               try
                  delete(h)
                  delete(l)
                  delete(sh)
                  delete(sl)
               end

               app.ElapsedtimeEditField.Value = output.tout(i);
               app.LongitudeEditField.Value = output.eta.Data(i,1);
               app.LatitudeEditField.Value = output.eta.Data(i,2);
               app.HeadingEditField.Value = output.eta.Data(i,3);

               app.SurgeEditField.Value = output.velo.Data(i,1);
               app.SwayEditField.Value = output.velo.Data(i,2);
               app.YawrateEditField.Value = output.velo.Data(i,3);

               app.AngleEditField.Value = output.alpha.Data(i,1);
               app.SpeedEditField.Value = output.propellerSpeed.Data(1);
               app.TorqueEditField.Value = output.propellerDynamics.Data(1,3,i);
               app.FxEditField.Value = output.propellerDynamics.Data(1,1,i);
               app.FyEditField.Value = output.propellerDynamics.Data(1,2,i);

               app.DistancetopathEditField.Value = output.dist.Data(i,1);
               app.ProjectedpointxEditField.Value = output.ProjectedPoint.Data(1,1,i);
               app.ProjectedpointyEditField.Value = output.ProjectedPoint.Data(1,2,i);
               app.LookaheadpointxEditField.Value = output.LookaheadPoint.Data(1,1,i);
               app.LookaheadpointyEditField.Value = output.LookaheadPoint.Data(1,2,i);
               app.BetaangleEditField.Value = output.beta.Data(i,1);

               frame = getframe(app.UIFigure);
               for k = 1:5
                  writeVideo(app.v,frame);
               end

               if app.estop
                  break;
               end

            end
         end


         %          ShipSimVisualiser3D(app.UIAxes);
         app.StandingbyLabel.Text = 'Finished visualisation';
         close(app.v);

      end

      % Button pushed function: StopButton
      function StopButtonPushed(app, event)
         app.estop = true;
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         % Get the file path for locating images
         pathToMLAPP = fileparts(mfilename('fullpath'));
         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'MATLAB App';
         app.TabGroup = uitabgroup(app.UIFigure);
         app.ShipSimulationTab = uitab(app.TabGroup);
         app.ShipSimulationTab.Title = 'Ship Simulation';
         app.UIAxes = uiaxes(app.ShipSimulationTab);
         title(app.UIAxes, 'Ship Visualiser')
         xlabel(app.UIAxes, 'Latitude')
         ylabel(app.UIAxes, 'Longitude')
         zlabel(app.UIAxes, 'Z')
         app.StartButton = uibutton(app.ShipSimulationTab, 'push');
         app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
         app.StartButton.Text = 'Start';
         app.TabGroup2 = uitabgroup(app.ShipSimulationTab);
         app.ShipTab = uitab(app.TabGroup2);
         app.ShipTab.Title = 'Ship';
         app.LongitudeEditFieldLabel = uilabel(app.ShipTab);
         app.LongitudeEditFieldLabel.Text = 'Longitude';
         app.LongitudeEditField = uieditfield(app.ShipTab, 'numeric');
         app.LatitudeEditFieldLabel = uilabel(app.ShipTab);
         app.LatitudeEditFieldLabel.Text = 'Latitude';
         app.LatitudeEditField = uieditfield(app.ShipTab, 'numeric');
         app.HeadingEditFieldLabel = uilabel(app.ShipTab);
         app.HeadingEditFieldLabel.Text = 'Heading';
         app.HeadingEditField = uieditfield(app.ShipTab, 'numeric');
         app.SurgeEditFieldLabel = uilabel(app.ShipTab);
         app.SurgeEditFieldLabel.Text = 'Surge';
         app.SurgeEditField = uieditfield(app.ShipTab, 'numeric');
         app.SwayEditFieldLabel = uilabel(app.ShipTab);
         app.SwayEditFieldLabel.Text = 'Sway';
         app.SwayEditField = uieditfield(app.ShipTab, 'numeric');
         app.YawrateEditFieldLabel = uilabel(app.ShipTab);
         app.YawrateEditFieldLabel.Text = 'Yaw rate';
         app.YawrateEditField = uieditfield(app.ShipTab, 'numeric');
         app.PropellerTab = uitab(app.TabGroup2);
         app.PropellerTab.Title = 'Propeller';
         app.AngleEditFieldLabel = uilabel(app.PropellerTab);
         app.AngleEditFieldLabel.Text = 'Angle';
         app.AngleEditField = uieditfield(app.PropellerTab, 'numeric');
         app.SpeedEditFieldLabel = uilabel(app.PropellerTab);
         app.SpeedEditFieldLabel.Text = 'Speed';
         app.SpeedEditField = uieditfield(app.PropellerTab, 'numeric');
         app.FxEditFieldLabel = uilabel(app.PropellerTab);
         app.FxEditFieldLabel.Text = 'Fx';
         app.FxEditField = uieditfield(app.PropellerTab, 'numeric');
         app.FyEditFieldLabel = uilabel(app.PropellerTab);
         app.FyEditFieldLabel.Text = 'Fy';
         app.FyEditField = uieditfield(app.PropellerTab, 'numeric');
         app.TorqueEditFieldLabel = uilabel(app.PropellerTab);
         app.TorqueEditFieldLabel.Text = 'Torque';
         app.TorqueEditField = uieditfield(app.PropellerTab, 'numeric');
         app.PathFollowingTab = uitab(app.TabGroup2);
         app.PathFollowingTab.Title = 'Path Following';
         app.BetaangleEditFieldLabel = uilabel(app.PathFollowingTab);
         app.BetaangleEditFieldLabel.Text = 'Beta angle';
         app.BetaangleEditField = uieditfield(app.PathFollowingTab, 'numeric');
         app.ProjectedpointxEditFieldLabel = uilabel(app.PathFollowingTab);
         app.ProjectedpointxEditFieldLabel.Text = {'Projected'; 'point x'};
         app.ProjectedpointxEditField = uieditfield(app.PathFollowingTab, 'numeric');
         app.ProjectedpointyEditFieldLabel = uilabel(app.PathFollowingTab);
         app.ProjectedpointyEditFieldLabel.Text = {'Projected'; 'point y'};
         app.ProjectedpointyEditField = uieditfield(app.PathFollowingTab, 'numeric');
         app.LookaheadpointxEditFieldLabel = uilabel(app.PathFollowingTab);
         app.LookaheadpointxEditFieldLabel.Text = {'Lookahead'; 'point x'};
         app.LookaheadpointxEditField = uieditfield(app.PathFollowingTab, 'numeric');
         app.LookaheadpointyEditFieldLabel = uilabel(app.PathFollowingTab);
         app.LookaheadpointyEditFieldLabel.Text = {'Lookahead'; 'point y'};
         app.LookaheadpointyEditField = uieditfield(app.PathFollowingTab, 'numeric');
         app.DistancetopathEditFieldLabel = uilabel(app.PathFollowingTab);
         app.DistancetopathEditFieldLabel.Text = {'Distance'; 'to path'};
         app.DistancetopathEditField = uieditfield(app.PathFollowingTab, 'numeric');
         app.StatusLabel = uilabel(app.ShipSimulationTab);
         app.StatusLabel.Text = 'Status';
         app.StandingbyLabel = uilabel(app.ShipSimulationTab);
         app.StandingbyLabel.Text = 'Standing by';
         app.ElapsedtimeEditFieldLabel = uilabel(app.ShipSimulationTab);
         app.ElapsedtimeEditFieldLabel.Text = 'Elapsed time';
         app.ElapsedtimeEditField = uieditfield(app.ShipSimulationTab, 'numeric');
         app.SimulationtimeEditFieldLabel = uilabel(app.ShipSimulationTab);
         app.SimulationtimeEditFieldLabel.Text = 'Simulation time';
         app.SimulationtimeEditField = uieditfield(app.ShipSimulationTab, 'numeric');
         app.StopButton = uibutton(app.ShipSimulationTab, 'push');
         app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
         app.StopButton.Text = 'Stop';
         app.AppDescriptionTab = uitab(app.TabGroup);
         app.AppDescriptionTab.Title = 'App Description';
         app.Label = uilabel(app.AppDescriptionTab);
         app.Label.Text = 'UNIVERSITY OF TECHNOLOGY SYDNEY';
         app.Label_2 = uilabel(app.AppDescriptionTab);
         app.Label_2.Text = {'FACULTY OF ENGINEERING AND INFORMATION TECHNOLOGY'; ''};
         app.Image = uiimage(app.AppDescriptionTab);
         app.Image.ImageSource = fullfile(pathToMLAPP, 'ShipVisualiser_v1.2', 'Image', 'Picture1.png');
         app.Label_3 = uilabel(app.AppDescriptionTab);
         app.Label_3.Text = '43019 - Design in Mechanical and Mechatronic Systems';
         app.Label_4 = uilabel(app.AppDescriptionTab);
         app.Label_4.Text = 'Team 6 - Automatic Maneuvering Abilities';
         app.Label_5 = uilabel(app.AppDescriptionTab);
         app.Label_5.Text = {'Group member:'; 'Anson Antony'; 'Ho Minh Quang Ngo'; 'Charles Craig'; 'Minh Quan Vu'; 'Hoang Trung Le'; 'Xinyi Wu'};
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = app1

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end